classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ScanBarcodeButton           matlab.ui.control.Button
        ExtractedtextTextArea       matlab.ui.control.TextArea
        ExtractedtextTextAreaLabel  matlab.ui.control.Label
        ScanLabelButton             matlab.ui.control.Button
        BrowseImagesButton          matlab.ui.control.Button
        Image                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        img %image to be processed
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImagesButton
        function BrowseImagesButtonPushed(app, event)
            [file,path] = uigetfile('*.png;*.jpg;*.jpeg')  %get a png or jpg file
            fullFileName = fullfile(path, file);
            app.img = imread(fullFileName);
            [x,y, numberOfColorChannels] = size(app.img)
            if (contains(file, ".jpg") == 1  || contains(file, ".jpeg") == 1) && x > 2000 && y > 2000
                app.img = imrotate(app.img, -90);
            end
            %if image is grayscale, convert to color
            if numberOfColorChannels <= 1
                app.img = cat(3,app.img,app.img,app.img);
            end
            app.Image.ImageSource = app.img;
            %refocus app
            figure(app.UIFigure);
        end

        % Callback function
        function CaptureButtonPushed(app, event)
            cam = webcam;
            app.img = snapshot(cam);
            app.Image.ImageSource = app.img;
        end

        % Button pushed function: ScanLabelButton
        function ScanLabelButtonPushed(app, event)
            %prompt user to wait for processing
            h = msgbox('Please wait...');
            %TODO check to make sure image is selected or captured
            
            %preprocessing
            processedImg = preprocess(app.img);
            %put grayscale image back to m x n x 3 format
            RGB = cat(3,processedImg,processedImg,processedImg);
            app.Image.ImageSource = RGB;
            %text reading
            cellArrayText = performocr(processedImg);
            %post process text
            finalString = postprocess(cellArrayText);
            %put data in text area
            app.ExtractedtextTextArea.Value = (finalString);
            close(h);
        end

        % Button pushed function: ScanBarcodeButton
        function ScanBarcodeButtonPushed(app, event)
            %prompt user to wait for processing
            h = msgbox('Please wait...');
            
            %preprocessing
            processedImg = preprocess(app.img);
            %put grayscale image back to m x n x 3 format
            RGB = cat(3,processedImg,processedImg,processedImg);
            app.Image.ImageSource = RGB;
            
            %imshow(processedImg);
            
            %read barcode
            [msg,detectedFormat,loc] = readBarcode(app.img,'1D');
            
            %put data in text area
            app.ExtractedtextTextArea.Value = (msg);
            
            if msg == ""
                app.ExtractedtextTextArea.Value = ("Could not read the barcode. Please try again");
            else
                % api call to get barcode information
                api = "https://world.openfoodfacts.org/api/v0/product/"+ msg +".json";
            
                apioutput = webread(api);
                apioutput.product
                
                brand = apioutput.product.brands;
                %brand = apioutput.product.brand_owner;
                productName = apioutput.product.product_name;
                
                nameTotal = brand + ": " + newline + productName;
               
                
                calories = apioutput.product.nutriments.energy_kcal_serving;
                calorieUnit = apioutput.product.nutriments.energy_kcal_unit;
                calTotal = "Calories per serving: " + calories + " " + calorieUnit;
                
                protein = apioutput.product.nutriments.proteins_serving;
                proteinUnit = apioutput.product.nutriments.proteins_unit;
                proteinTotal = "Protein per serving: "+ protein + " " + proteinUnit;
                
                sodium = apioutput.product.nutriments.sodium_serving;
                sodiumUnit = apioutput.product.nutriments.sodium_unit;
                sodiumTotal = "Sodium per serving: " +sodium + " " + sodiumUnit;
                
                carbs = apioutput.product.nutriments.carbohydrates_serving;
                carbsUnit = apioutput.product.nutriments.carbohydrates_unit;
                carbsTotal = "Carbohydrates per serving: " + carbs + " " + carbsUnit;
                
                cholesterol = apioutput.product.nutriments.cholesterol_serving;
                cholesterolUnit = apioutput.product.nutriments.cholesterol_unit;
                cholesterolTotal = "Cholesterol per serving: " + cholesterol + " " + cholesterolUnit;
                
                satfat = apioutput.product.nutriments.saturated_fat_serving;
                satfatUnit = apioutput.product.nutriments.saturated_fat_unit;
                satfatTotal = "Saturated fat per serving: " + satfat + " " + satfatUnit;
                
                transfat = apioutput.product.nutriments.trans_fat_serving;
                transfatUnit = apioutput.product.nutriments.trans_fat_unit;
                transfatTotal = "Trans fats per serving: " + transfat + " " + transfatUnit;
                
                
                finalString = [nameTotal newline calTotal newline proteinTotal newline sodiumTotal newline carbsTotal newline cholesterolTotal newline satfatTotal newline transfatTotal];
                
                app.ExtractedtextTextArea.Value = (finalString);
                
                
            end
            
            
            close(h);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.BrowseImagesButton = uibutton(app.UIFigure, 'push');
            app.BrowseImagesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImagesButtonPushed, true);
            app.BrowseImagesButton.Text = 'Browse Images';
            app.ScanLabelButton = uibutton(app.UIFigure, 'push');
            app.ScanLabelButton.ButtonPushedFcn = createCallbackFcn(app, @ScanLabelButtonPushed, true);
            app.ScanLabelButton.Text = 'Scan Label';
            app.ExtractedtextTextAreaLabel = uilabel(app.UIFigure);
            app.ExtractedtextTextAreaLabel.Text = 'Extracted text';
            app.ExtractedtextTextArea = uitextarea(app.UIFigure);
            app.ScanBarcodeButton = uibutton(app.UIFigure, 'push');
            app.ScanBarcodeButton.ButtonPushedFcn = createCallbackFcn(app, @ScanBarcodeButtonPushed, true);
            app.ScanBarcodeButton.Text = 'Scan Barcode';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end